classdef projectionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        appGUI                          matlab.ui.Figure
        ROIDetectionParametersPanel     matlab.ui.container.Panel
        MinSpacingpixelsEditFieldLabel  matlab.ui.control.Label
        MinSpacingpixels                matlab.ui.control.NumericEditField
        MinRadiuspixelsEditFieldLabel   matlab.ui.control.Label
        MinRadiuspixels                 matlab.ui.control.NumericEditField
        MinAreapixels2EditFieldLabel    matlab.ui.control.Label
        MinAreapixels2                  matlab.ui.control.NumericEditField
        GaussianRefinementCheckBox      matlab.ui.control.CheckBox
        SearchAreapixelsLabel           matlab.ui.control.Label
        GaussianSearchArea              matlab.ui.control.NumericEditField
        ChannelAlignmentPanel           matlab.ui.container.Panel
        Channel1Button                  matlab.ui.control.Button
        Channel2Button                  matlab.ui.control.Button
        Channel3Button                  matlab.ui.control.Button
        alignmentFiles1                 matlab.ui.control.TextArea
        alignmentFiles2                 matlab.ui.control.TextArea
        alignmentFiles3                 matlab.ui.control.TextArea
        ImageStacksPanel                matlab.ui.container.Panel
        ImageStack2Button               matlab.ui.control.Button
        ImageStack3Button               matlab.ui.control.Button
        imageStackFiles2                matlab.ui.control.TextArea
        imageStackFiles3                matlab.ui.control.TextArea
        ImageStack1Button               matlab.ui.control.Button
        imageStackFiles1                matlab.ui.control.TextArea
        ImageMaskPanel                  matlab.ui.container.Panel
        LoadMasksButton                 matlab.ui.control.Button
        maskFiles                       matlab.ui.control.TextArea
        MaskChannelButtonGroup          matlab.ui.container.ButtonGroup
        maskChannel1                    matlab.ui.control.RadioButton
        maskChannel2                    matlab.ui.control.RadioButton
        maskChannel3                    matlab.ui.control.RadioButton
        CreateMaskCheckBox              matlab.ui.control.CheckBox
        ProjectROIsButton               matlab.ui.control.Button
        ChangeFilePathButton            matlab.ui.control.Button
        filePath                        matlab.ui.control.TextArea
        ImagePropertiesPanel            matlab.ui.container.Panel
        FrameRateHzEditFieldLabel       matlab.ui.control.Label
        FrameRateHz                     matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        channelName1                    matlab.ui.control.EditField
        EditField_2Label                matlab.ui.control.Label
        channelName2                    matlab.ui.control.EditField
        EditField_3Label                matlab.ui.control.Label
        channelName3                    matlab.ui.control.EditField
        setChannelNameText              matlab.ui.control.Label
        NumberofChannelsLabel           matlab.ui.control.Label
        numChannels                     matlab.ui.control.NumericEditField
        currentFilePathText             matlab.ui.control.Label
        BackgroundSubtractionPanel      matlab.ui.container.Panel
        backgroundChannel1              matlab.ui.control.CheckBox
        backgroundChannel2              matlab.ui.control.CheckBox
        backgroundChannel3              matlab.ui.control.CheckBox
        ApplytoLabel                    matlab.ui.control.Label
        BackgroundSubtractionMethod     matlab.ui.control.DropDown
        PixelsEditFieldLabel            matlab.ui.control.Label
        PixelsEditField                 matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        alignmentPath1 % Full File Path for storing and output
        alignmentPath2
        alignmentPath3
        imageStacksPath1
        imageStacksPath2
        imageStacksPath3
        maskPath
    end
    
    methods (Access = public)
        
        % Select Files from Button Click
        function [files,path] = selectFiles(app)
            if isempty(app.filePath.Value{1})
                [files,path] = uigetfile('*.tif','Select one or more mask files',...
                    'Multiselect','on');
                if path
                    app.filePath.Value = cellstr(path);
                end
            else
                [files,path] = uigetfile('*.tif','Select one or more mask files',...
                    app.filePath.Value{1}, 'Multiselect','on');
            end
            % if no files selected, files == 0 with class double
            if isa(files,'double')
                files = char([]);
                path = char([]);
                % if only one file selectd, class(files) = char;
            elseif isa(files,'char')
                files = cellstr(files);
            end
            
        end
        
        function imageData = runProjectROIs(app)
            % convert objects into data stucture for projectROIs
            % should eventually do this as in OOP but for now...
            % should hopefully let the stucture be unchange but GUI objects changed so
            % external functions are less affected.
            imageData = struct;
            
            % Image Properties Panel
            imageData.ImageProperties.numChannels = app.numChannels.Value;
            imageData.ImageProperties.frameRateHz = app.FrameRateHz.Value;
            imageData.ImageProperties.channelNames = {};
            imageData.ImageProperties.channelNames{1} = app.channelName1.Value;
            imageData.ImageProperties.channelNames{2} = app.channelName2.Value;
            imageData.ImageProperties.channelNames{3} = app.channelName3.Value;
            
            % ROI Detection Parameters Panel
            imageData.DetectionParameters.minSpacing = app.MinSpacingpixels.Value;
            imageData.DetectionParameters.minRadius = app.MinRadiuspixels.Value;
            imageData.DetectionParameters.minArea = app.MinAreapixels2.Value;
            imageData.DetectionParameters.gaussianRefine = app.GaussianRefinementCheckBox.Value;
            if imageData.DetectionParameters.gaussianRefine
                imageData.DetectionParameters.gaussianRefineArea = app.GaussianSearchArea.Value;
            else
                imageData.DetectionParameters.gaussianRefineArea = 0;
            end
            
            % Alignment Files Panel
            imageData.ChannelAlignment.path = {};
            imageData.ChannelAlignment.path{1,1} = app.alignmentPath1; 
            imageData.ChannelAlignment.path{2,1} = app.alignmentPath2; 
            imageData.ChannelAlignment.path{3,1} = app.alignmentPath3; 
            imageData.ChannelAlignment.files = {};
            imageData.ChannelAlignment.files{1,1} = app.alignmentFiles1.Value;
            imageData.ChannelAlignment.files{2,1} = app.alignmentFiles2.Value;
            imageData.ChannelAlignment.files{3,1} = app.alignmentFiles3.Value;
            
            
            % Image Stacks
            imageData.ImageStacks.path = {};
            imageData.ImageStacks.path{1,1} = app.imageStacksPath1; 
            imageData.ImageStacks.path{2,1} = app.imageStacksPath2; 
            imageData.ImageStacks.path{3,1} = app.imageStacksPath3; 
            imageData.ImageStacks.files = {};
            imageData.ImageStacks.files{1,1} = app.imageStackFiles1.Value;
            imageData.ImageStacks.files{2,1} = app.imageStackFiles2.Value;
            imageData.ImageStacks.files{3,1} = app.imageStackFiles3.Value;
            
            % Image Mask
            imageData.ImageMask.createMask = app.CreateMaskCheckBox.Value;
            imageData.ImageMask.path = app.maskPath; 
            imageData.ImageMask.maskFiles = app.maskFiles.Value;
            if app.maskChannel1.Value;
                imageData.ImageMask.maskChannel = 1;
            elseif app.maskChannel2.Value
                imageData.ImageMask.maskChannel = 2;
            elseif app.maskChannel3.Value
                imageData.ImageMask.maskChannel = 3;
            end
            
            % Background Subtraction
            imageData.BackgroundSubtraction.applyToChannels = [0,0,0];
            imageData.BackgroundSubtraction.channels(1) = app.backgroundChannel1.Value;
            imageData.BackgroundSubtraction.channels(2) = app.backgroundChannel2.Value;
            imageData.BackgroundSubtraction.channels(3) = app.backgroundChannel3.Value;
            imageData.BackgroundSubtraction.method = app.BackgroundSubtractionMethod.Value;
            % need to edit still to grab necessary input values..
            
            % run with external function
            %imageData = projectROIs(imageData);
            
            % save userData in file path
            
            [saveFile,savePath] = uiputfile('*.mat','Save Image Data',app.filePath.Value{1});
            save([savePath,saveFile], 'imageData');
            
            disp('done')
            
        end
    end
    

    methods (Access = private)

        % Value changed function: MinSpacingpixels
        function callback_minSpacing(app, event)
            value = app.MinSpacingpixels.Value;
        end

        % Value changed function: MinRadiuspixels
        function callback_minRadius(app, event)
            value = app.MinRadiuspixels.Value;
        end

        % Value changed function: MinAreapixels2
        function callback_minArea(app, event)
            value = app.MinAreapixels2.Value;
        end

        % Callback function
        function callback_restrictMask(app, event)
            value = app.RestrictCheckBox.Value;
        end

        % Button pushed function: Channel1Button
        function load_alignment1(app, event)
            [app.alignmentFiles1.Value,app.alignmentPath1] = selectFiles(app);
        end

        % Button pushed function: ChangeFilePathButton
        function button_selectFilePath(app, event)
            new_path = uigetdir;
            if new_path
                % was the same path selected?
                if ~strcmp(app.filePath.Value, new_path)
                    app.filePath.Value = new_path;
                    differentPathSelected = 1;
                else
                    differentPathSelected = 0;
                end
            else
                app.filePath.Value = char([]);
                differentPathSelected = 1;
            end
            if differentPathSelected
                % clear out fi
                app.alignmentFiles1.Value = char([]);
                app.alignmentFiles2.Value = char([]);
                app.alignmentFiles3.Value = char([]);
                app.imageStackFiles1.Value = char([]);
                app.imageStackFiles2.Value = char([]);
                app.imageStackFiles3.Value = char([]);
                app.maskFiles.Value = char([]);
            end
        end

        % Button pushed function: Channel2Button
        function load_alignment2(app, event)
          [app.alignmentFiles2.Value,app.alignmentPath2] = selectFiles(app);
        end

        % Button pushed function: Channel3Button
        function load_alignment3(app, event)
            [app.alignmentFiles3.Value,app.alignmentPath3] = selectFiles(app);
        end

        % Button pushed function: ImageStack1Button
        function load_imageStack1(app, event)
            [app.imageStackFiles1.Value,app.imageStacksPath1] = selectFiles(app);
        end

        % Button pushed function: ImageStack2Button
        function load_imageStack2(app, event)
             [app.imageStackFiles2.Value,app.imageStacksPath2] = selectFiles(app);
        end

        % Button pushed function: ImageStack3Button
        function load_imageStack3(app, event)
            [app.imageStackFiles3.Value,app.imageStacksPath3] = selectFiles(app);
        end

        % Button pushed function: LoadMasksButton
        function load_imageMask(app, event)
            [app.maskFiles.Value,app.maskPath] = selectFiles(app);
        end

        % Button pushed function: ProjectROIsButton
        function button_projectROIs(app, event)
            imageData = runProjectROIs(app);
            % close the app once ROIs have been projected 
            % delete(app);
        end

        % Value changed function: numChannels
        function callback_numChannels(app, event)
            value = app.numChannels.Value;
            % make sure all are default value first
            
            app.Channel2Button.FontColor = [0,0,0];
            app.Channel2Button.Enable = 1;
            app.alignmentFiles2.BackgroundColor = [1,1,1];
            app.ImageStack2Button.FontColor = [0,0,0];
            app.ImageStack2Button.Enable = 1;
            app.imageStackFiles2.BackgroundColor = [1,1,1];
            app.maskChannel2.Enable = 2;
            app.backgroundChannel2.Enable = 1;
            app.channelName2.Enable = 1;
            app.channelName2.BackgroundColor = [1,1,1];
            
            
            app.Channel3Button.FontColor = [0,0,0];
            app.Channel3Button.Enable = 1;
            app.alignmentFiles3.BackgroundColor = [1,1,1];
            app.ImageStack3Button.FontColor = [0,0,0];
            app.ImageStack3Button.Enable = 1;
            app.imageStackFiles3.BackgroundColor = [1,1,1];
            app.maskChannel3.Enable = 1;
            app.backgroundChannel3.Enable = 1;
            app.channelName3.Enable = 1;
            app.channelName3.BackgroundColor = [1,1,1];
            
            % modify window options based on value
            if value < 3
                app.Channel3Button.FontColor = [0.8,0.8,0.8];
                app.Channel3Button.Enable = 0;
                app.alignmentFiles3.BackgroundColor = [0.8,0.8,0.8];
                app.ImageStack3Button.FontColor = [0.8,0.8,0.8];
                app.ImageStack3Button.Enable = 0;
                app.imageStackFiles3.BackgroundColor = [0.8,0.8,0.8];
                app.maskChannel3.Enable = 0;
                app.maskChannel3.Value = 0;
                app.maskChannel1.Value = 1; % default to channel 1
                app.backgroundChannel3.Enable = 0;
                app.backgroundChannel3.Value = 0; 
                app.channelName3.Enable = 0;
                app.channelName3.BackgroundColor = [0.8,0.8,0.8];
                
            end
            
            if value < 2
                app.Channel2Button.FontColor = [0.8,0.8,0.8];
                app.Channel2Button.Enable = 0;
                app.alignmentFiles2.BackgroundColor = [0.8,0.8,0.8];
                app.ImageStack2Button.FontColor = [0.8,0.8,0.8];
                app.ImageStack2Button.Enable = 0;
                app.imageStackFiles2.BackgroundColor = [0.8,0.8,0.8];
                app.maskChannel2.Enable = 0;
                app.maskChannel2.Value = 0;
                app.maskChannel1.Value = 1; % default to channel 1
                app.backgroundChannel2.Enable = 0;
                app.backgroundChannel3.Value = 1;
                app.channelName2.Enable = 0;
                app.channelName2.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: CreateMaskCheckBox
        function callback_createMask(app, event)
            value = app.CreateMaskCheckBox.Value;
            if value 
                app.LoadMasksButton.Enable = 0; 
                app.maskFiles.Value = char([]); 
                app.maskFiles.BackgroundColor = [0.8,0.8,0.8]; 
            else
                app.LoadMasksButton.Enable = 1;  
                app.maskFiles.BackgroundColor = [1,1,1];
            end
        end

        % Value changed function: GaussianSearchArea
        function callback_gaussSearchArea(app, event)
            value = app.GaussianSearchArea.Value;
        end

        % Value changed function: GaussianRefinementCheckBox
        function callback_gaussRefine(app, event)
            value = app.GaussianRefinementCheckBox.Value;
            if value
                app.GaussianSearchArea.Enable = 1;
                app.GaussianSearchArea.BackgroundColor = [1,1,1];
                app.GaussianSearchArea.FontColor = [0,0,0];
            else
                app.GaussianSearchArea.Enable = 1;
                app.GaussianSearchArea.BackgroundColor = [0.8,0.8,0.8];
                app.GaussianSearchArea.FontColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: channelName1
        function callback_nameChannel1(app, event)
            value = app.channelName1.Value;
        end

        % Value changed function: channelName2
        function callback_nameChannel2(app, event)
            value = app.channelName2.Value;
        end

        % Value changed function: channelName3
        function callback_nameChannel3(app, event)
            value = app.channelName3.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.appGUI = uifigure;
            app.appGUI.Name = 'ROI Projection';
            app.ROIDetectionParametersPanel = uipanel(app.appGUI);
            app.ROIDetectionParametersPanel.Title = 'ROI Detection Parameters';
            app.MinSpacingpixelsEditFieldLabel = uilabel(app.ROIDetectionParametersPanel);
            app.MinSpacingpixelsEditFieldLabel.Text = 'Min Spacing (pixels)';
            app.MinSpacingpixels = uieditfield(app.ROIDetectionParametersPanel, 'numeric');
            app.MinSpacingpixels.ValueChangedFcn = createCallbackFcn(app, @callback_minSpacing, true);
            app.MinSpacingpixels.Value = 5;
            app.MinRadiuspixelsEditFieldLabel = uilabel(app.ROIDetectionParametersPanel);
            app.MinRadiuspixelsEditFieldLabel.Text = 'Min Radius (pixels)';
            app.MinRadiuspixels = uieditfield(app.ROIDetectionParametersPanel, 'numeric');
            app.MinRadiuspixels.Limits = [0 100];
            app.MinRadiuspixels.ValueChangedFcn = createCallbackFcn(app, @callback_minRadius, true);
            app.MinRadiuspixels.Value = 2.5;
            app.MinAreapixels2EditFieldLabel = uilabel(app.ROIDetectionParametersPanel);
            app.MinAreapixels2EditFieldLabel.Text = 'Min Area (pixels^2)';
            app.MinAreapixels2 = uieditfield(app.ROIDetectionParametersPanel, 'numeric');
            app.MinAreapixels2.Limits = [0 100];
            app.MinAreapixels2.ValueChangedFcn = createCallbackFcn(app, @callback_minArea, true);
            app.MinAreapixels2.Value = 2;
            app.GaussianRefinementCheckBox = uicheckbox(app.ROIDetectionParametersPanel);
            app.GaussianRefinementCheckBox.ValueChangedFcn = createCallbackFcn(app, @callback_gaussRefine, true);
            app.GaussianRefinementCheckBox.Text = 'Gaussian Refinement';
            app.SearchAreapixelsLabel = uilabel(app.ROIDetectionParametersPanel);
            app.SearchAreapixelsLabel.Text = 'Search Area (pixels)';
            app.GaussianSearchArea = uieditfield(app.ROIDetectionParametersPanel, 'numeric');
            app.GaussianSearchArea.Limits = [1 100];
            app.GaussianSearchArea.ValueChangedFcn = createCallbackFcn(app, @callback_gaussSearchArea, true);
            app.GaussianSearchArea.Editable = 'off';
            app.GaussianSearchArea.Value = 16;
            app.ChannelAlignmentPanel = uipanel(app.appGUI);
            app.ChannelAlignmentPanel.Title = 'Channel Alignment';
            app.Channel1Button = uibutton(app.ChannelAlignmentPanel, 'push');
            app.Channel1Button.ButtonPushedFcn = createCallbackFcn(app, @load_alignment1, true);
            app.Channel1Button.Text = 'Channel 1';
            app.Channel2Button = uibutton(app.ChannelAlignmentPanel, 'push');
            app.Channel2Button.ButtonPushedFcn = createCallbackFcn(app, @load_alignment2, true);
            app.Channel2Button.Text = {'Channel 2'; ''};
            app.Channel3Button = uibutton(app.ChannelAlignmentPanel, 'push');
            app.Channel3Button.ButtonPushedFcn = createCallbackFcn(app, @load_alignment3, true);
            app.Channel3Button.Text = {'Channel 3'; ''};
            app.alignmentFiles1 = uitextarea(app.ChannelAlignmentPanel);
            app.alignmentFiles2 = uitextarea(app.ChannelAlignmentPanel);
            app.alignmentFiles3 = uitextarea(app.ChannelAlignmentPanel);
            app.ImageStacksPanel = uipanel(app.appGUI);
            app.ImageStacksPanel.Title = 'Image Stacks ';
            app.ImageStack2Button = uibutton(app.ImageStacksPanel, 'push');
            app.ImageStack2Button.ButtonPushedFcn = createCallbackFcn(app, @load_imageStack2, true);
            app.ImageStack2Button.Text = 'Image Stack 2';
            app.ImageStack3Button = uibutton(app.ImageStacksPanel, 'push');
            app.ImageStack3Button.ButtonPushedFcn = createCallbackFcn(app, @load_imageStack3, true);
            app.ImageStack3Button.Text = 'Image Stack 3';
            app.imageStackFiles2 = uitextarea(app.ImageStacksPanel);
            app.imageStackFiles3 = uitextarea(app.ImageStacksPanel);
            app.ImageStack1Button = uibutton(app.ImageStacksPanel, 'push');
            app.ImageStack1Button.ButtonPushedFcn = createCallbackFcn(app, @load_imageStack1, true);
            app.ImageStack1Button.Text = 'Image Stack 1';
            app.imageStackFiles1 = uitextarea(app.ImageStacksPanel);
            app.ImageMaskPanel = uipanel(app.appGUI);
            app.ImageMaskPanel.Title = 'Image Mask';
            app.LoadMasksButton = uibutton(app.ImageMaskPanel, 'push');
            app.LoadMasksButton.ButtonPushedFcn = createCallbackFcn(app, @load_imageMask, true);
            app.LoadMasksButton.Text = {'Load Mask(s)'; ''};
            app.maskFiles = uitextarea(app.ImageMaskPanel);
            app.MaskChannelButtonGroup = uibuttongroup(app.ImageMaskPanel);
            app.MaskChannelButtonGroup.Title = 'Mask Channel';
            app.maskChannel1 = uiradiobutton(app.MaskChannelButtonGroup);
            app.maskChannel1.Text = {'Channel 1'; ''};
            app.maskChannel1.Value = true;
            app.maskChannel2 = uiradiobutton(app.MaskChannelButtonGroup);
            app.maskChannel2.Text = 'Channel 2';
            app.maskChannel3 = uiradiobutton(app.MaskChannelButtonGroup);
            app.maskChannel3.Text = {'Channel 3'; ''};
            app.CreateMaskCheckBox = uicheckbox(app.ImageMaskPanel);
            app.CreateMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @callback_createMask, true);
            app.CreateMaskCheckBox.Text = 'Create Mask';
            app.ProjectROIsButton = uibutton(app.appGUI, 'push');
            app.ProjectROIsButton.ButtonPushedFcn = createCallbackFcn(app, @button_projectROIs, true);
            app.ProjectROIsButton.Text = 'Project ROIs';
            app.ChangeFilePathButton = uibutton(app.appGUI, 'push');
            app.ChangeFilePathButton.ButtonPushedFcn = createCallbackFcn(app, @button_selectFilePath, true);
            app.ChangeFilePathButton.Text = 'Change File Path';
            app.filePath = uitextarea(app.appGUI);
            app.ImagePropertiesPanel = uipanel(app.appGUI);
            app.ImagePropertiesPanel.Title = 'Image Properties';
            app.FrameRateHzEditFieldLabel = uilabel(app.ImagePropertiesPanel);
            app.FrameRateHzEditFieldLabel.Text = 'Frame Rate (Hz)';
            app.FrameRateHz = uieditfield(app.ImagePropertiesPanel, 'numeric');
            app.FrameRateHz.Limits = [0 Inf];
            app.FrameRateHz.Value = 10;
            app.EditFieldLabel = uilabel(app.ImagePropertiesPanel);
            app.EditFieldLabel.Text = '1';
            app.channelName1 = uieditfield(app.ImagePropertiesPanel, 'text');
            app.channelName1.ValueChangedFcn = createCallbackFcn(app, @callback_nameChannel1, true);
            app.channelName1.Value = 'Channel 1';
            app.EditField_2Label = uilabel(app.ImagePropertiesPanel);
            app.EditField_2Label.Text = '2';
            app.channelName2 = uieditfield(app.ImagePropertiesPanel, 'text');
            app.channelName2.ValueChangedFcn = createCallbackFcn(app, @callback_nameChannel2, true);
            app.channelName2.Value = 'Channel 2';
            app.EditField_3Label = uilabel(app.ImagePropertiesPanel);
            app.EditField_3Label.Text = '3';
            app.channelName3 = uieditfield(app.ImagePropertiesPanel, 'text');
            app.channelName3.ValueChangedFcn = createCallbackFcn(app, @callback_nameChannel3, true);
            app.channelName3.Value = 'Channel 3';
            app.setChannelNameText = uilabel(app.ImagePropertiesPanel);
            app.setChannelNameText.Text = 'Set Channel Name';
            app.NumberofChannelsLabel = uilabel(app.ImagePropertiesPanel);
            app.NumberofChannelsLabel.Text = 'Number of Channels';
            app.numChannels = uieditfield(app.ImagePropertiesPanel, 'numeric');
            app.numChannels.Limits = [1 3];
            app.numChannels.ValueChangedFcn = createCallbackFcn(app, @callback_numChannels, true);
            app.numChannels.Value = 3;
            app.currentFilePathText = uilabel(app.appGUI);
            app.currentFilePathText.Text = 'Current File Path: ';
            app.BackgroundSubtractionPanel = uipanel(app.appGUI);
            app.BackgroundSubtractionPanel.Title = 'Background Subtraction';
            app.backgroundChannel1 = uicheckbox(app.BackgroundSubtractionPanel);
            app.backgroundChannel1.Text = 'Channel 1';
            app.backgroundChannel2 = uicheckbox(app.BackgroundSubtractionPanel);
            app.backgroundChannel2.Text = 'Channel 2';
            app.backgroundChannel3 = uicheckbox(app.BackgroundSubtractionPanel);
            app.backgroundChannel3.Text = 'Channel 3';
            app.ApplytoLabel = uilabel(app.BackgroundSubtractionPanel);
            app.ApplytoLabel.Text = 'Apply to: ';
            app.BackgroundSubtractionMethod = uidropdown(app.BackgroundSubtractionPanel);
            app.BackgroundSubtractionMethod.Items = {'White Top Hat', 'Rolling Ball Radius'};
            app.BackgroundSubtractionMethod.Value = 'White Top Hat';
            app.PixelsEditFieldLabel = uilabel(app.appGUI);
            app.PixelsEditFieldLabel.Text = 'Pixels';
            app.PixelsEditField = uieditfield(app.appGUI, 'numeric');
            app.PixelsEditField.Limits = [1 500];
            app.PixelsEditField.Editable = 'off';
            app.PixelsEditField.Value = 50;
        end
    end

    methods (Access = public)

        % Construct app
        function app = projectionGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.appGUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.appGUI)
        end
    end
end